classdef Interfaz_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InformacindeltramoPanel   matlab.ui.container.Panel
        TipoDropDownLabel         matlab.ui.control.Label
        TipoDropDown              matlab.ui.control.DropDown
        PendienteLabel            matlab.ui.control.Label
        PendienteEditField        matlab.ui.control.NumericEditField
        TramoEditFieldLabel       matlab.ui.control.Label
        TramoEditField            matlab.ui.control.NumericEditField
        AbscisamEditFieldLabel    matlab.ui.control.Label
        AbscisamEditField         matlab.ui.control.NumericEditField
        Panel                     matlab.ui.container.Panel
        AgregarButton             matlab.ui.control.Button
        EliminarButton            matlab.ui.control.Button
        CancelarButton            matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        NorteEditFieldLabel       matlab.ui.control.Label
        NorteEditField            matlab.ui.control.NumericEditField
        EsteEditFieldLabel        matlab.ui.control.Label
        EsteEditField             matlab.ui.control.NumericEditField
        CoordenadasCheckBox       matlab.ui.control.CheckBox
        Button                    matlab.ui.control.Button
        CurvaturamLabel           matlab.ui.control.Label
        CurvaturamEditField       matlab.ui.control.EditField
        Panel_2                   matlab.ui.container.Panel
        AgregarimagenButton       matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UITable                   matlab.ui.control.Table
        TerminarycalificarButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        inf_tramo % Información tramo
        ID %Numero de tramo
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
          
        load('..\mat\estado_carga.mat')
           
            %IDENTIFICACÍON DEL TRAMO
            ID = app.TramoEditField.Value;
            %LONGITUD DEL TRAMO
            Longitud = app.AbscisamEditField.Value;
            %TIPOLOGIA
            Tipologi= app.TipoDropDown.Value
            %CURVATURA
            Curva = app.CurvaturamEditField.Value;
            %PENDIENTE
            Pendiente = app.PendienteEditField.Value;
            
            %IMAGEN
            if im == 1
                Imagen = 'Cargado'
            else im = 0
                Imagen= 'No cargado'
            end
            
            %%if app.AgregarimagenButton.Pushed==1
            %%Imagen='Cargado'
            %%end


             CoordenadaX=app.EsteEditField.Value
             CoordenadaY=app.NorteEditField.Value
             
            %AGREGAR INFORMACIÓN
           VALORES_TRAMO = [ID {Tipologi} Longitud Pendiente Curva Imagen CoordenadaX CoordenadaY];
           app.UITable.Data = [app.inf_tramo;VALORES_TRAMO];
           app.inf_tramo = app.UITable.Data;

           %%VALOR POR DEFECTO  
           % IDENTIFICACÍON DEL TRAMO
            app.TramoEditField.Value = (ID+1);
            %LONGITUD DEL TRAMO
            app.AbscisamEditField.Value = 100;
            %TIPOLOGIA
            app.TipoDropDown.Value = 'Tangente'
            %CURVATURA
            app.CurvaturamEditField.Editable = 0;
            app.CurvaturamEditField.Value = '-';
            %PENDIENTE
            app.PendienteEditField.Value = 0;
            %GUARDAR FOTOGRAMA
            load('..\mat\infor_img_alt.mat')
            img_tramo = imread(fotograma);
            nom_tramo=sprintf('%d.jpg',ID)
            nombre_img = strcat('..\fotogramas\',nom_tramo);
            imwrite(img_tramo,nombre_img);
            %SIN IMAGEN
            sn_img=imread('..\img\SN_CARGA.png');
            imshow(sn_img,'Parent',app.UIAxes);
            im = 0

            save('../mat/estado_carga','im')
           app.CoordenadasCheckBox.Value=0
           app.EsteEditField.Value=0
           app.NorteEditField.Value=0
        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            value = app.TipoDropDown.Value;
            if app.TipoDropDown.Value == 'Curva';
                app.CurvaturamEditField.Editable = 1;
            else app.TipoDropDown.Value == 'Tangente';
                app.CurvaturamEditField.Editable =0;
            end
        end

        % Value changed function: CoordenadasCheckBox
        function CoordenadasCheckBoxValueChanged(app, event)
            value = app.CoordenadasCheckBox.Value;
            if app.CoordenadasCheckBox.Value == 1; 
                app.EsteEditField.Visible = 'On';
                app.NorteEditField.Visible = 'On';
              
            else
                app.CoordenadasCheckBox.Value=0
                app.EsteEditField.Value=0
                app.NorteEditField.Value=0
            end
               xt=app.EsteEditField.Value;
               yt=app.NorteEditField.Value
               save('../mat/preproceso_coord',"xt","yt")
        end

        % Button pushed function: AgregarimagenButton
        function AgregarimagenButtonPushed(app, event)

        %UBICACION Y NOMBRE DEL FOTOGRAMA     
        [Filename, pathname]=uigetfile({ '*.jpg*';'*.png*'},'seleccione los archivos','Multiselect','on');
        ubi_img = pathname;
        numero_archivos=dir(fullfile(ubi_img,'*.jpg'));
        texto_ruta=numero_archivos(1).folder;
        union_nombre= strcat('\',Filename);
        fotograma=strcat(texto_ruta,union_nombre);
        save('..\mat\infor_img_alt',"fotograma");
        img_tramo = imread(fotograma);
        imshow(img_tramo,'Parent',app.UIAxes);    
        
        if app.AgregarimagenButton.Enable=='on'
            im= 1
        end
        save('../mat/estado_carga','im')
        %OBTENCION COORDENADAS IMG
        info = imfinfo(Filename)
         isfield(info,'ModelTiepointTag')
                   if ans==1
        CoordXY=info.ModelTiepointTag;
        CoordeX=CoordXY(1,4);
        CoordeY=CoordXY(1,5);
        app.CoordenadasCheckBox.Value == 1
                app.EsteEditField.Visible = 'On';
                app.NorteEditField.Visible = 'On';
                app.EsteEditField.Value=CoordeX
                app.NorteEditField.Value=CoordeY
            end
                
             CoordenadaX=app.EsteEditField.Value
             CoordenadaY=app.NorteEditField.Value
                
        %RUTA DEL FOTOGRAMA  
        
        
        end

        % Button pushed function: TerminarycalificarButton
        function TerminarycalificarButtonPushed(app, event)
     clear('../mat/preproceso_tramo',"tramo")       
            %DIMENSIONAR LA ESTRUCTURA DE GUARDADO
            app.inf_tramo=app.UITable.Data
            num_filas = size(app.inf_tramo,1);
          
            %ESTRUCTURA DE INFORMACIÓN

            for num=1:num_filas
                tramo(num).Tramos = app.inf_tramo{num,1}
            
                tramo(num).Distancia = app.inf_tramo{num,3}
            
                tramo(num).Pendiente = app.inf_tramo{num,4}
                             
                tramo(num).Radio = app.inf_tramo{num,5}
                TIPO = str2double(app.inf_tramo{num,5})
                if TIPO>0
                    tramo(num).Radio=str2double(app.inf_tramo{num,5})
                else 
                    tramo(num).Radio=99999
                    
                end
                tramo(num).Xt=app.inf_tramo{num,7};
              
                tramo(num).yt=app.inf_tramo{num,8};
       
                if TIPO>0
                    k = 1
                    tramo(num).k= k
                else 
                    k = 0
                    tramo(num).k= k
                end
          
           Time_trams(num)=app.inf_tramo{num,1}
            end
          save('../mat/preproceso_tramo',"tramo")
          save("../mat/preproceso_tiempo_img","Time_trams")
          
          Interfaz_Atributos
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
          camb1 ={'Ingrese la fila del tramo que desee eliminar: '};
            tit1 = 'Eliminar tramo';
            progresivaini={'0'};
            respuesta1=inputdlg(camb1,tit1,1,progresivaini);
            rn=str2double(respuesta1)
           app.UITable.Data(rn,:)=[];
           app.inf_tramo=app.UITable.Data
           num_filas = size(app.inf_tramo,1);
           for num=1:num_filas
               app.UITable.Data(num,1)={num}
           end
           app.inf_tramo=app.UITable.Data
           
           app.TramoEditField.Value=num_filas+1
           
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
   
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            m=msgbox('En tramos rectos se toman valores de radio como infinitos(99999)','Información');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformacindeltramoPanel = uipanel(app.UIFigure);
            app.InformacindeltramoPanel.Title = 'Información del tramo';
            app.TipoDropDownLabel = uilabel(app.InformacindeltramoPanel);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.InformacindeltramoPanel);
            app.TipoDropDown.Items = {'Tangente', 'Curva'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'Tangente';
            app.PendienteLabel = uilabel(app.InformacindeltramoPanel);
            app.PendienteLabel.Text = 'Pendiente (%):';
            app.PendienteEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.TramoEditFieldLabel = uilabel(app.InformacindeltramoPanel);
            app.TramoEditFieldLabel.Text = 'Tramo:';
            app.TramoEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.TramoEditField.Value = 1;
            app.AbscisamEditFieldLabel = uilabel(app.InformacindeltramoPanel);
            app.AbscisamEditFieldLabel.Text = 'Abscisa (m):';
            app.AbscisamEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.AbscisamEditField.ValueDisplayFormat = '%.0f';
            app.AbscisamEditField.Value = 100;
            app.Panel = uipanel(app.InformacindeltramoPanel);
            app.AgregarButton = uibutton(app.Panel, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.Panel, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.CancelarButton = uibutton(app.Panel, 'push');
            app.CancelarButton.Text = 'Cancelar';
            app.Panel_3 = uipanel(app.InformacindeltramoPanel);
            app.NorteEditFieldLabel = uilabel(app.Panel_3);
            app.NorteEditFieldLabel.Enable = 'off';
            app.NorteEditFieldLabel.Text = 'Norte:';
            app.NorteEditField = uieditfield(app.Panel_3, 'numeric');
            app.NorteEditField.Visible = 'off';
            app.EsteEditFieldLabel = uilabel(app.Panel_3);
            app.EsteEditFieldLabel.Enable = 'off';
            app.EsteEditFieldLabel.Text = 'Este:';
            app.EsteEditField = uieditfield(app.Panel_3, 'numeric');
            app.EsteEditField.Visible = 'off';
            app.CoordenadasCheckBox = uicheckbox(app.Panel_3);
            app.CoordenadasCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoordenadasCheckBoxValueChanged, true);
            app.CoordenadasCheckBox.Text = 'Coordenadas';
            app.Button = uibutton(app.InformacindeltramoPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.CurvaturamLabel = uilabel(app.InformacindeltramoPanel);
            app.CurvaturamLabel.Text = 'Curvatura (m):';
            app.CurvaturamEditField = uieditfield(app.InformacindeltramoPanel, 'text');
            app.CurvaturamEditField.Value = '-';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AgregarimagenButton = uibutton(app.Panel_2, 'push');
            app.AgregarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarimagenButtonPushed, true);
            app.AgregarimagenButton.Text = 'Agregar imagen';
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Tramo'; 'Tipo'; 'Abscisa'; 'Pendiente'; 'Radio'; 'Imagen'; 'CoordenadaX'; 'CoordenadaY'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.TerminarycalificarButton = uibutton(app.UIFigure, 'push');
            app.TerminarycalificarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarycalificarButtonPushed, true);
            app.TerminarycalificarButton.Text = 'Terminar y calificar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end